classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ChooseCSVFileButton        matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        SliderLabel                matlab.ui.control.Label
        Slider                     matlab.ui.control.Slider
        play                       matlab.ui.control.Button
        XLabel                     matlab.ui.control.Label
        gpx1                       matlab.ui.control.NumericEditField
        YEditFieldLabel            matlab.ui.control.Label
        gpy1                       matlab.ui.control.NumericEditField
        UCDlandmarksDropDownLabel  matlab.ui.control.Label
        landmarks                  matlab.ui.control.DropDown
        status                     matlab.ui.control.EditField
    end

    
    properties (Access = private)
        map = 0;% Description
    end
    
    methods (Access = private)
        
        function plot(app,x,y)
            imshow('CampusMap.png', 'parent', app.UIAxes);
            app.UIAxes.YDir = 'reverse';   

            hold (app.UIAxes, 'on');
            plot(app.UIAxes, x, y, 'o', 'MarkerFaceColor', 'r', 'MarkerSize', 6);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            disableDefaultInteractivity(app.UIAxes);
            imshow('CampusMap.png', 'parent', app.UIAxes);
        end

        % Button pushed function: ChooseCSVFileButton
        function ChooseCSVFileButtonPushed(app, event)
            disableDefaultInteractivity(app.UIAxes);
            [file,~] = uigetfile('*.csv');
             if isequal(file, 0)
                app.status.Value = "No CSV file detected!";
                drawnow;
                figure(app.UIFigure)               
               return;
             else
                data = readtable(file);
                drawnow;
                figure(app.UIFigure)
                if(table2array(data(3,2)) > 0)
                   latitude = table2array(data(:,2)); longitude = table2array(data(:,3));
                else
                   latitude = table2array(data(:,3)); longitude = table2array(data(:,2));
                end 
                
                app.map = mapping;           
                app.map.gpy2Pixel(latitude);
                app.map.gpx2Pixel(longitude);

                indx = app.map.x > 1800;
                indy = app.map.y > 1800;
                
                if(any(indx) || any(indy))
                    app.map.x(indx) = [];
                    app.map.x(indy) = [];
                    app.map.y(indx) = [];
                    app.map.y(indy) = [];
                end
                
                app.plot(app.map.x,app.map.y);
             end
        end

        % Value changed function: gpy1
        function gpy1ValueChanged(app, event)
            value = app.gpy1.Value;
            cla(app.UIAxes, 'reset');
            if(app.map == 0)
                app.map = mapping;
                app.map.gpx2Pixel(app.gpx1.Value);
                app.map.gpy2Pixel(value);
                app.plot(app.map.x, app.map.y);
            else
                app.map.gpx2Pixel(app.gpx1.Value);
                app.map.gpy2Pixel(value);
                app.plot(app.map.x, app.map.y);
            end

        end

        % Button pushed function: play
        function playButtonPushed(app, event)
            if(app.map == 0)
                app.status.Value = "Please choose csv data!";
                imshow('meme.jpeg','parent',app.UIAxes)
            else
                curve = animatedline(app.UIAxes,'Color', 'r');
                for i = 1:length(app.map.x)
                    addpoints(curve, app.map.x(i), app.map.y(i));
                    pause(.5); 
                    drawnow;
                end
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
           if(app.map == 0)
                app.status.Value = "Please choose csv data!";
                imshow('meme.jpeg','parent',app.UIAxes)
            else
                curve = animatedline(app.UIAxes,'Color', 'r');
                for i = 1:ceil(value)
                    addpoints(curve, app.map.x(i), app.map.y(i)); 
                    drawnow;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ChooseCSVFileButton = uibutton(app.UIFigure, 'push');
            app.ChooseCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseCSVFileButtonPushed, true);
            app.ChooseCSVFileButton.Text = 'Choose CSV File';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, 'Longitude')
            ylabel(app.UIAxes, 'Latitude')
            app.UIAxes.XTick = [];
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 10];
            app.Slider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [];
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.play.Text = 'Connect dots';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.gpx1 = uieditfield(app.UIFigure, 'numeric');
            app.gpx1.ValueDisplayFormat = '%11.9g';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y:';
            app.gpy1 = uieditfield(app.UIFigure, 'numeric');
            app.gpy1.ValueDisplayFormat = '%11.9g';
            app.gpy1.ValueChangedFcn = createCallbackFcn(app, @gpy1ValueChanged, true);
            app.UCDlandmarksDropDownLabel = uilabel(app.UIFigure);
            app.UCDlandmarksDropDownLabel.Text = 'UCD landmarks';
            app.landmarks = uidropdown(app.UIFigure);
            app.status = uieditfield(app.UIFigure, 'text');
            app.status.Editable = 'off';
            app.status.Value = 'Status bar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end